classdef CORWAVE_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        uipanel10              matlab.ui.container.Panel
        pushbutton45           matlab.ui.control.Button
        uipanel11              matlab.ui.container.Panel
        valsbatch_pbutton      matlab.ui.control.Button
        standbatch_pbutton     matlab.ui.control.Button
        dbbatch_pbutton        matlab.ui.control.Button
        isobatch_pbutton       matlab.ui.control.Button
        hrvbatch_pbutton       matlab.ui.control.Button
        hrtbatch_pbutton       matlab.ui.control.Button
        fastpardat_pbutton     matlab.ui.control.Button
        createparfile_pbutton  matlab.ui.control.Button
        reset_pbutton          matlab.ui.control.Button
        uipanel3               matlab.ui.container.Panel
        text13                 matlab.ui.control.Label
        beatfreq               matlab.ui.control.EditField
        vessel2_pop            matlab.ui.control.DropDown
        vessel1_pop            matlab.ui.control.DropDown
        text12                 matlab.ui.control.Label
        text11                 matlab.ui.control.Label
        text10                 matlab.ui.control.Label
        pwlab2_pop             matlab.ui.control.DropDown
        text9                  matlab.ui.control.Label
        pwlab1_pop             matlab.ui.control.DropDown
        beat_text              matlab.ui.control.Label
        beat_pop               matlab.ui.control.DropDown
        set_text               matlab.ui.control.Label
        bhlevel_edit           matlab.ui.control.EditField
        text3                  matlab.ui.control.Label
        text2                  matlab.ui.control.Label
        rawfreq                matlab.ui.control.EditField
        set_pop                matlab.ui.control.DropDown
        text8                  matlab.ui.control.Label
        file_text              matlab.ui.control.Label
        browse_push            matlab.ui.control.Button
        uipanel6               matlab.ui.container.Panel
        bmetronome_pbutton     matlab.ui.control.Button
        uipanel1               matlab.ui.container.Panel
        pbutton_mf             matlab.ui.control.Button
        getbeatdata_pbutton    matlab.ui.control.Button
        vals_pbutton           matlab.ui.control.Button
        iso_pbutton            matlab.ui.control.Button
        db_pbutton             matlab.ui.control.Button
        stand_pbutton          matlab.ui.control.Button
        hrv_pbutton            matlab.ui.control.Button
        HRturb_pbutton         matlab.ui.control.Button
        text1                  matlab.ui.control.Label
        Untitled_3             matlab.ui.container.ContextMenu
    end

    
    methods (Access = private)
        function synch_pbutton_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in synch_pbutton.
            
            % hObject    handle to synch_pbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            choice = questdlg('WHAT KIND OF FILES?','',...
            	'pardat','CNTpardat','pardat');
            
            if strcmp(choice,'pardat')==1; AverageSync, end
            if strcmp(choice,'CNTpardat')==1; AverageSyncCNT, end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CORWAVE_OpeningFcn(app, varargin)
            % --- Executes just before CORWAVE is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to CORWAVE (see VARARGIN)
            
            % Choose default command line output for CORWAVE
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % Setup default data & clear workspace
            evalin( 'base', 'clearvars -except s' )
            global filename; filename = NaN;
            global fs2; fs2 = 100;
            global fs3; fs3 = 5;
            global param_set; param_set = 3;
            global beatmode; beatmode = 1;
            global height; height = 0;
            global cbf1ch; cbf1ch = 1;
            global cbf2ch; cbf2ch = 2;
            global cbf1vessel; cbf1vessel = 1;
            global cbf2vessel; cbf2vessel = 4;
            
            %Copy parameter values to ws
            assignin('base','filename',filename);
            assignin('base','fs2',fs2);
            assignin('base','fs3',fs3);
            assignin('base','param_set',param_set);
            assignin('base','beatmode',beatmode)
            assignin('base','height',height)
            assignin('base','cbf1ch',cbf1ch)
            assignin('base','cbf2ch',cbf2ch)
            assignin('base','cbf1vessel','MCAR')
            assignin('base','cbf2vessel','MCAL')
            
            %Setup default values
            set(handles.file_text,'String','');
            set(handles.set_pop,'Value',3);
            set(handles.beat_pop,'Value',1);
            set(handles.rawfreq,'String','100');
            set(handles.beatfreq,'String','5');
            set(handles.bhlevel_edit,'String','0');
            set(handles.pwlab1_pop,'Value',1);
            set(handles.pwlab2_pop,'Value',2);
            set(handles.vessel1_pop,'Value',1);
            set(handles.vessel2_pop,'Value',4);
            set(handles.vessel1_pop,'string',{'MCAR','ACAR','PCAR','MCAL','ACAL','PCAL'});
            set(handles.vessel2_pop,'string',{'MCAR','ACAR','PCAR','MCAL','ACAL','PCAL'});
        end

        % Button pushed function: HRturb_pbutton
        function HRturb_pbutton_Callback(app, event)
            % --- Executes on button press in HRturb_pbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to HRturb_pbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global filename fs2 fs3 beatmode param_set height cbf1ch cbf2ch cbf1vessel cbf2vessel
            filename = evalin('base','filename');
            beatmode = evalin('base','beatmode');
            fs2 = evalin('base','fs2');
            fs3 = evalin('base','fs3');
            param_set = evalin('base','param_set');
            height = evalin('base','height');
            cbf1ch = evalin('base','cbf1ch');
            cbf1vessel = evalin('base','cbf1vessel');
            cbf2ch = evalin('base','cbf2ch');
            cbf2vessel = evalin('base','cbf2vessel');
            
            choice = questdlg('Already have a parameter file?','Skip beat detection',...
            	'Yes','No','No');
            
                if strcmp(choice,'No')==1;
            
                    if isnan(filename) == 1;
                         msgbox('Please select a *.TXT (T2-DWL) // *.PAR // *.mat (Labchart) file!','Invalid File', 'error');
                         return
                    else
                        [ParameterData] = beatdata(filename,fs2,fs3,beatmode,param_set,height,cbf1ch,cbf2ch);
                    end
            
                else
                    parfile = uigetfile;
                    load(parfile);
                    filename = parfile(1:end-11);
                    assignin('base','ParameterData',ParameterData);
                    assignin('base','filename',filename);
                    ParameterData = evalin('base','ParameterData');
                end
            
            if isnan(filename) == 1;
                msgbox('Please select a file!','Invalid Parameter', 'error');
            else
                HRTAnalyser(filename)
            end
        end

        % Value changed function: beat_pop
        function beat_pop_Callback(app, event)
            % --- Executes on selection change in beat_pop.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to beat_pop (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns beat_pop contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from beat_pop
            beatmode = get(hObject,'Value');
            assignin('base','beatmode',beatmode);
        end

        % Value changed function: beatfreq
        function beatfreq_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to beatfreq (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of beatfreq as text
            %        str2double(get(hObject,'String')) returns contents of beatfreq as a double
            fs3 = str2double(get(hObject,'String'));
            assignin('base','fs3',fs3);
        end

        % Value changed function: bhlevel_edit
        function bhlevel_edit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to bhlevel_edit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of bhlevel_edit as text
            %        str2double(get(hObject,'String')) returns contents of bhlevel_edit as a double
            height= str2double(get(hObject,'String'));
            assignin('base','height',height);
        end

        % Button pushed function: bmetronome_pbutton
        function bmetronome_pbutton_Callback(app, event)
            % --- Executes on button press in bmetronome_pbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to bmetronome_pbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            breathmetro_pres
        end

        % Button pushed function: browse_push
        function browse_push_Callback(app, event)
            % --- Executes on button press in browse_push.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to browse_push (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global filename;
            filename = uigetfile({'*.mat'},'Select file');
            if filename == 0;
                filename = NaN;
            else
            end
            assignin('base','filename',filename);
            set(handles.file_text,'String', strcat('File:',filename));
        end

        % Button pushed function: createparfile_pbutton
        function createparfile_pbutton_Callback(app, event)
            %CREATE PAR --> to use in Panerai AZEVEDO
            % --- Executes on button press in createparfile_pbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to createparfile_pbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            createPAR
        end

        % Button pushed function: db_pbutton
        function db_pbutton_Callback(app, event)
            % --- Executes on button press in db_pbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to db_pbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)% hObject    handle to co1_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global filename fs2 fs3 beatmode param_set height cbf1ch cbf2ch cbf1vessel cbf2vessel
            filename = evalin('base','filename');
            beatmode = evalin('base','beatmode');
            fs2 = evalin('base','fs2');
            fs3 = evalin('base','fs3');
            param_set = evalin('base','param_set');
            height = evalin('base','height');
            cbf1ch = evalin('base','cbf1ch');
            cbf1vessel = evalin('base','cbf1vessel');
            cbf2ch = evalin('base','cbf2ch');
            cbf2vessel = evalin('base','cbf2vessel');
            
            choice = questdlg('Already have a parameter file?','Skip beat detection',...
            	'Yes','No','No');
            
                if strcmp(choice,'No')==1;
            
                    if isnan(filename) == 1;
                        msgbox('Please select a file!','Invalid Parameter', 'error');
                    else
                        [ParameterData] = beatdata(filename,fs2,fs3,beatmode,param_set,height,cbf1ch,cbf2ch);
                    end
            
                else
                    parfile = uigetfile;
                    load(parfile);
                    filename = parfile(1:end-11);
                    assignin('base','ParameterData',ParameterData);
                    assignin('base','filename',filename);
                    ParameterData = evalin('base','ParameterData');
                end
            
            DeepBreathingAnalyser(filename,ParameterData);
        end

        % Button pushed function: fastpardat_pbutton
        function fastpardat_pbutton_Callback(app, event)
            %CREATE FASTPARDAT --> w/visual control of artifacts
            % --- Executes on button press in fastpardat_pbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to fastpardat_pbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            fastbeatdata
        end

        % Button pushed function: getbeatdata_pbutton
        function getbeatdata_pbutton_Callback(app, event)
            % --- Executes on button press in getbeatdata_pbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to getbeatdata_pbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global filename fs2 fs3 beatmode param_set height cbf1ch cbf2ch cbf1vessel cbf2vessel
            filename = evalin('base','filename');
            beatmode = evalin('base','beatmode');
            fs2 = evalin('base','fs2');
            fs3 = evalin('base','fs3');
            param_set = evalin('base','param_set');
            height = evalin('base','height');
            cbf1ch = evalin('base','cbf1ch');
            cbf1vessel = evalin('base','cbf1vessel');
            cbf2ch = evalin('base','cbf2ch');
            cbf2vessel = evalin('base','cbf2vessel');
            
            choice = questdlg('Already have a parameter file?','Skip beat detection',...
            	'Yes','No','No');
            
                if strcmp(choice,'No')==1;
            
                    if isnan(filename) == 1;
                        msgbox('Please select a file!','Invalid Parameter', 'error');
                        return
                    elseif strcmp(filename(end-3:end),{'.mat','.PAR','.xl0','.XL0','.TXT','.txt','.CNT'}) == 0
                             msgbox('Please select apropriate file: << *.TXT, *.mat, *.PAR, *.xl0, *.CNT >>','Invalid File', 'error');
                             return
                    else
                        if  strcmp(filename(end-3:end),'.CNT') == 1 ;
                             [ParameterData] = CNTdata(filename);
                        else
                             [ParameterData] = beatdata(filename,fs2,fs3,beatmode,param_set,height,cbf1ch,cbf2ch);
                        end
            
                    end
            
                else
                    parfile = uigetfile('*.mat');
                    load(parfile);
                    if strcmp(parfile(end-12:end),'CNTpardat.mat') == 1
                        filename = parfile(1:end-10);
                    else
                        filename = parfile(1:end-11);
                    end
                    assignin('base','ParameterData',ParameterData);
                    assignin('base','filename',filename);
                    ParameterData = evalin('base','ParameterData');
                end
            
                filename  =evalin('base','filename');
            
            FileInspector(filename,ParameterData)
        end

        % Button pushed function: hrv_pbutton
        function hrv_pbutton_Callback(app, event)
            % --- Executes on button press in hrv_pbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to hrv_pbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global filename fs2 fs3 beatmode param_set height cbf1ch cbf2ch cbf1vessel cbf2vessel
            filename = evalin('base','filename');
            beatmode = evalin('base','beatmode');
            fs2 = evalin('base','fs2');
            fs3 = evalin('base','fs3');
            param_set = evalin('base','param_set');
            height = evalin('base','height');
            cbf1ch = evalin('base','cbf1ch');
            cbf1vessel = evalin('base','cbf1vessel');
            cbf2ch = evalin('base','cbf2ch');
            cbf2vessel = evalin('base','cbf2vessel');
            
            choice = questdlg('Already have a parameter file?','Skip beat detection',...
            	'Yes','No','No');
            
                if strcmp(choice,'No')==1;
            
                    if isnan(filename) == 1;
                        msgbox('Please select a file!','Invalid Parameter', 'error');
                    else
                        [ParameterData] = beatdata(filename,fs2,fs3,beatmode,param_set,height,cbf1ch,cbf2ch);
                    end
            
                else
                    parfile = uigetfile;
                    load(parfile);
                    filename = parfile(1:end-11);
                    assignin('base','ParameterData',ParameterData);
                    assignin('base','filename',filename);
                    ParameterData = evalin('base','ParameterData');
                end
            
            [tasksettings,taskresults] = CardioVarAnalyser(filename,ParameterData);
        end

        % Button pushed function: hrvbatch_pbutton
        function hrvbatch_pbutton_Callback(app, event)
            % --- Executes on button press in hrvbatch_pbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to hrvbatch_pbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            AverageHRV
        end

        % Button pushed function: iso_pbutton
        function iso_pbutton_Callback(app, event)
            % --- Executes on button press in iso_pbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to iso_pbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global filename fs2 fs3 beatmode param_set height cbf1ch cbf2ch cbf1vessel cbf2vessel
            filename = evalin('base','filename');
            beatmode = evalin('base','beatmode');
            fs2 = evalin('base','fs2');
            fs3 = evalin('base','fs3');
            param_set = evalin('base','param_set');
            height = evalin('base','height');
            cbf1ch = evalin('base','cbf1ch');
            cbf1vessel = evalin('base','cbf1vessel');
            cbf2ch = evalin('base','cbf2ch');
            cbf2vessel = evalin('base','cbf2vessel');
            
            choice = questdlg('Already have a parameter file?','Skip beat detection',...
            	'Yes','No','No');
            
                if strcmp(choice,'No')==1;
            
                    if isnan(filename) == 1;
                        msgbox('Please select a file!','Invalid Parameter', 'error');
                    else
                        [ParameterData] = beatdata(filename,fs2,fs3,beatmode,param_set,height,cbf1ch,cbf2ch);
                    end
            
                else
                    parfile = uigetfile;
                    load(parfile);
                    filename = parfile(1:end-11);
                    assignin('base','ParameterData',ParameterData);
                    assignin('base','filename',filename);
                    ParameterData = evalin('base','ParameterData');
                end
            
             [taskresults,tasksettings]= IsometricAnalyser(filename,ParameterData);
        end

        % Button pushed function: pbutton_mf
        function pbutton_mf_Callback(app, event)
            % --- Executes on button press in pbutton_mf.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pbutton_mf (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % handles    structure with handles and user data (see GUIDATA)
            global filename fs2 fs3 beatmode param_set height cbf1ch cbf2ch cbf1vessel cbf2vessel
            filename = evalin('base','filename');
            beatmode = evalin('base','beatmode');
            fs2 = evalin('base','fs2');
            fs3 = evalin('base','fs3');
            param_set = evalin('base','param_set');
            height = evalin('base','height');
            cbf1ch = evalin('base','cbf1ch');
            cbf1vessel = evalin('base','cbf1vessel');
            cbf2ch = evalin('base','cbf2ch');
            cbf2vessel = evalin('base','cbf2vessel');
            
            choice = questdlg('Already have a parameter file?','Skip beat detection',...
            	'Yes','No','No');
            
                if strcmp(choice,'No')==1;
            
                    if isnan(filename) == 1;
                        msgbox('Please select a file!','Invalid Parameter', 'error');
                    else
                        [ParameterData] = beatdata(filename,fs2,fs3,beatmode,param_set,height,cbf1ch,cbf2ch);
                    end
            
                else
                    parfile = uigetfile;
                    load(parfile);
                    filename = parfile(1:end-11);
                    assignin('base','ParameterData',ParameterData);
                    assignin('base','filename',filename);
                    ParameterData = evalin('base','ParameterData');
                end
            
            [tasksettings,taskresults] = MFAnalyser(filename,ParameterData);
        end

        % Value changed function: pwlab1_pop
        function pwlab1_pop_Callback(app, event)
            % --- Executes on selection change in pwlab1_pop.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pwlab1_pop (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cbf1ch = get(hObject,'Value');
            assignin('base','cbf1ch',cbf1ch);
        end

        % Value changed function: pwlab2_pop
        function pwlab2_pop_Callback(app, event)
            % --- Executes on selection change in pwlab2_pop.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pwlab2_pop (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cbf2ch = get(hObject,'Value');
            assignin('base','cbf2ch',cbf2ch);
        end

        % Value changed function: rawfreq
        function rawfreq_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to rawfreq (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of rawfreq as text
            %        str2double(get(hObject,'String')) returns contents of rawfreq as a double
            fs2 = str2double(get(hObject,'String'));
            assignin('base','fs2',fs2);
        end

        % Button pushed function: reset_pbutton
        function reset_pbutton_Callback(app, event)
            %RESET
            % --- Executes on button press in reset_pbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to reset_pbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            CORWAVE
        end

        % Value changed function: set_pop
        function set_pop_Callback(app, event)
            % --- Executes on selection change in set_pop.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to set_pop (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns set_pop contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from set_pop
            param_set = get(hObject,'Value');
            assignin('base','param_set',param_set);
        end

        % Button pushed function: stand_pbutton
        function stand_pbutton_Callback(app, event)
            % --- Executes on button press in stand_pbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to stand_pbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global filename fs2 fs3 beatmode param_set height cbf1ch cbf2ch cbf1vessel cbf2vessel
            filename = evalin('base','filename');
            beatmode = evalin('base','beatmode');
            fs2 = evalin('base','fs2');
            fs3 = evalin('base','fs3');
            param_set = evalin('base','param_set');
            height = evalin('base','height');
            cbf1ch = evalin('base','cbf1ch');
            cbf1vessel = evalin('base','cbf1vessel');
            cbf2ch = evalin('base','cbf2ch');
            cbf2vessel = evalin('base','cbf2vessel');
            
            choice = questdlg('Already have a parameter file?','Skip beat detection',...
            	'Yes','No','No');
            
                if strcmp(choice,'No')==1;
            
                    if isnan(filename) == 1;
                        msgbox('Please select a file!','Invalid Parameter', 'error');
                    else
                        [ParameterData] = beatdata(filename,fs2,fs3,beatmode,param_set,height,cbf1ch,cbf2ch);
                    end
            
                else
                    parfile = uigetfile;
                    load(parfile);
                    filename = parfile(1:end-11);
                    assignin('base','ParameterData',ParameterData);
                    assignin('base','filename',filename);
                    ParameterData = evalin('base','ParameterData');
                end
            
            [taskresults,tasksettings]= StandAnalyser(filename,ParameterData);
        end

        % Button pushed function: vals_pbutton
        function vals_pbutton_Callback(app, event)
            % --- Executes on button press in vals_pbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to vals_pbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global filename fs2 fs3 beatmode param_set height cbf1ch cbf2ch cbf1vessel cbf2vessel
            filename = evalin('base','filename');
            beatmode = evalin('base','beatmode');
            fs2 = evalin('base','fs2');
            fs3 = evalin('base','fs3');
            param_set = evalin('base','param_set');
            height = evalin('base','height');
            cbf1ch = evalin('base','cbf1ch');
            cbf1vessel = evalin('base','cbf1vessel');
            cbf2ch = evalin('base','cbf2ch');
            cbf2vessel = evalin('base','cbf2vessel');
            
            choice = questdlg('Already have a parameter file?','Skip beat detection',...
            	'Yes','No','No');
            
                if strcmp(choice,'No')==1;
            
                    if isnan(filename) == 1;
                        msgbox('Please select a file!','Invalid Parameter', 'error');
                    else
                        [ParameterData] = beatdata(filename,fs2,fs3,beatmode,param_set,height,cbf1ch,cbf2ch);
                    end
            
                else
                    parfile = uigetfile;
                    load(parfile);
                    filename = parfile(1:end-11);
                    assignin('base','ParameterData',ParameterData);
                    assignin('base','filename',filename);
                    ParameterData = evalin('base','ParameterData');
                end
            
            ValsAnalyser
        end

        % Value changed function: vessel1_pop
        function vessel1_pop_Callback(app, event)
            % --- Executes on selection change in vessel1_pop.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to vessel1_pop (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cbf1_items  = get(hObject,'String');
            cbf1vessel = get(hObject,'Value');
            cbf1vessel = cbf1_items{cbf1vessel};
            assignin('base','cbf1vessel',cbf1vessel);
        end

        % Value changed function: vessel2_pop
        function vessel2_pop_Callback(app, event)
            % --- Executes on selection change in vessel2_pop.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to vessel2_pop (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cbf2_items  = get(hObject,'String');
            cbf2vessel = get(hObject,'Value');
            cbf2vessel = cbf2_items{cbf2vessel};
            assignin('base','cbf2vessel',cbf2vessel);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'CORWAVE';
            app.figure1.Tag = 'figure1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = '   Autonomic Testing';
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = 'ANALYSER';
            app.uipanel6.Tag = 'uipanel6';
            app.uipanel1 = uipanel(app.uipanel6);
            app.uipanel1.Title = 'Select Analyser';
            app.uipanel1.Tag = 'uipanel1';
            app.HRturb_pbutton = uibutton(app.uipanel1, 'push');
            app.HRturb_pbutton.ButtonPushedFcn = createCallbackFcn(app, @HRturb_pbutton_Callback, true);
            app.HRturb_pbutton.Tag = 'HRturb_pbutton';
            app.HRturb_pbutton.Text = 'HR Turbulence';
            app.hrv_pbutton = uibutton(app.uipanel1, 'push');
            app.hrv_pbutton.ButtonPushedFcn = createCallbackFcn(app, @hrv_pbutton_Callback, true);
            app.hrv_pbutton.Tag = 'hrv_pbutton';
            app.hrv_pbutton.Text = 'HR Variability';
            app.stand_pbutton = uibutton(app.uipanel1, 'push');
            app.stand_pbutton.ButtonPushedFcn = createCallbackFcn(app, @stand_pbutton_Callback, true);
            app.stand_pbutton.Tag = 'stand_pbutton';
            app.stand_pbutton.Text = 'Standing';
            app.db_pbutton = uibutton(app.uipanel1, 'push');
            app.db_pbutton.ButtonPushedFcn = createCallbackFcn(app, @db_pbutton_Callback, true);
            app.db_pbutton.Tag = 'db_pbutton';
            app.db_pbutton.Text = 'Deep Breathing';
            app.iso_pbutton = uibutton(app.uipanel1, 'push');
            app.iso_pbutton.ButtonPushedFcn = createCallbackFcn(app, @iso_pbutton_Callback, true);
            app.iso_pbutton.Tag = 'iso_pbutton';
            app.iso_pbutton.Text = 'Isometric';
            app.vals_pbutton = uibutton(app.uipanel1, 'push');
            app.vals_pbutton.ButtonPushedFcn = createCallbackFcn(app, @vals_pbutton_Callback, true);
            app.vals_pbutton.Tag = 'vals_pbutton';
            app.vals_pbutton.Text = 'Valsalva';
            app.getbeatdata_pbutton = uibutton(app.uipanel1, 'push');
            app.getbeatdata_pbutton.ButtonPushedFcn = createCallbackFcn(app, @getbeatdata_pbutton_Callback, true);
            app.getbeatdata_pbutton.Tag = 'getbeatdata_pbutton';
            app.getbeatdata_pbutton.Text = 'Data Viewer';
            app.pbutton_mf = uibutton(app.uipanel1, 'push');
            app.pbutton_mf.ButtonPushedFcn = createCallbackFcn(app, @pbutton_mf_Callback, true);
            app.pbutton_mf.Tag = 'pbutton_mf';
            app.pbutton_mf.Text = 'Model Flow';
            app.bmetronome_pbutton = uibutton(app.uipanel6, 'push');
            app.bmetronome_pbutton.ButtonPushedFcn = createCallbackFcn(app, @bmetronome_pbutton_Callback, true);
            app.bmetronome_pbutton.Tag = 'bmetronome_pbutton';
            app.bmetronome_pbutton.Text = 'Breath metronome';
            app.browse_push = uibutton(app.figure1, 'push');
            app.browse_push.ButtonPushedFcn = createCallbackFcn(app, @browse_push_Callback, true);
            app.browse_push.Tag = 'browse_push';
            app.browse_push.Text = 'Browse file';
            app.file_text = uilabel(app.figure1);
            app.file_text.Tag = 'file_text';
            app.file_text.Text = '';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = ' Neurosonology Lab     Hospital Center São João       ';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Parameters';
            app.uipanel3.Tag = 'uipanel3';
            app.set_pop = uidropdown(app.uipanel3);
            app.set_pop.Items = {'ABP +  EKG', 'ABP +  EKG + TCD', 'ABP +  EKG + TCD + CO2', 'TCD +  CO2', 'TCD (only)'};
            app.set_pop.ValueChangedFcn = createCallbackFcn(app, @set_pop_Callback, true);
            app.set_pop.Tag = 'set_pop';
            app.set_pop.Value = 'ABP +  EKG';
            app.rawfreq = uieditfield(app.uipanel3, 'text');
            app.rawfreq.ValueChangedFcn = createCallbackFcn(app, @rawfreq_Callback, true);
            app.rawfreq.Tag = 'rawfreq';
            app.rawfreq.Value = '100';
            app.text2 = uilabel(app.uipanel3);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Raw Data ResFreq (Hz)';
            app.text3 = uilabel(app.uipanel3);
            app.text3.Tag = 'text3';
            app.text3.Text = 'BrHr level (mmHg)';
            app.bhlevel_edit = uieditfield(app.uipanel3, 'text');
            app.bhlevel_edit.ValueChangedFcn = createCallbackFcn(app, @bhlevel_edit_Callback, true);
            app.bhlevel_edit.Tag = 'bhlevel_edit';
            app.bhlevel_edit.Value = '0';
            app.set_text = uilabel(app.uipanel3);
            app.set_text.Tag = 'set_text';
            app.set_text.Text = 'Settings:';
            app.beat_pop = uidropdown(app.uipanel3);
            app.beat_pop.Items = {'ECG /mod. PanTompkins', 'ECG /autoEvent', 'ABP'};
            app.beat_pop.ValueChangedFcn = createCallbackFcn(app, @beat_pop_Callback, true);
            app.beat_pop.Tag = 'beat_pop';
            app.beat_pop.Value = 'ECG /mod. PanTompkins';
            app.beat_text = uilabel(app.uipanel3);
            app.beat_text.Tag = 'beat_text';
            app.beat_text.Text = 'Beatmode:';
            app.pwlab1_pop = uidropdown(app.uipanel3);
            app.pwlab1_pop.Items = {'channel 1', 'channel 2', 'channel 3', 'channel 4', 'channel 5', 'channel 6', 'channel 7', 'channel 8'};
            app.pwlab1_pop.ValueChangedFcn = createCallbackFcn(app, @pwlab1_pop_Callback, true);
            app.pwlab1_pop.Tag = 'pwlab1_pop';
            app.pwlab1_pop.Value = 'channel 1';
            app.text9 = uilabel(app.uipanel3);
            app.text9.Tag = 'text9';
            app.text9.Text = 'CBFV 1:';
            app.pwlab2_pop = uidropdown(app.uipanel3);
            app.pwlab2_pop.Items = {'channel 1', 'channel 2', 'channel 3', 'channel 4', 'channel 5', 'channel 6', 'channel 7', 'channel 8'};
            app.pwlab2_pop.ValueChangedFcn = createCallbackFcn(app, @pwlab2_pop_Callback, true);
            app.pwlab2_pop.Tag = 'pwlab2_pop';
            app.pwlab2_pop.Value = 'channel 1';
            app.text10 = uilabel(app.uipanel3);
            app.text10.Tag = 'text10';
            app.text10.Text = 'CBFV 2:';
            app.text11 = uilabel(app.uipanel3);
            app.text11.Tag = 'text11';
            app.text11.Text = 'PowerLab: ';
            app.text12 = uilabel(app.uipanel3);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Vessel:';
            app.vessel1_pop = uidropdown(app.uipanel3);
            app.vessel1_pop.Items = {'MCAR', 'ACAR', 'PCAR', 'MCAL', 'ACAL', 'PCAL'};
            app.vessel1_pop.ValueChangedFcn = createCallbackFcn(app, @vessel1_pop_Callback, true);
            app.vessel1_pop.Tag = 'vessel1_pop';
            app.vessel1_pop.Value = 'MCAR';
            app.vessel2_pop = uidropdown(app.uipanel3);
            app.vessel2_pop.Items = {'MCAR', 'ACAR', 'PCAR', 'MCAL', 'ACAL', 'PCAL'};
            app.vessel2_pop.ValueChangedFcn = createCallbackFcn(app, @vessel2_pop_Callback, true);
            app.vessel2_pop.Tag = 'vessel2_pop';
            app.vessel2_pop.Value = 'MCAR';
            app.beatfreq = uieditfield(app.uipanel3, 'text');
            app.beatfreq.ValueChangedFcn = createCallbackFcn(app, @beatfreq_Callback, true);
            app.beatfreq.Tag = 'beatfreq';
            app.beatfreq.Value = '100';
            app.text13 = uilabel(app.uipanel3);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Beat Data ResFreq (Hz)';
            app.reset_pbutton = uibutton(app.figure1, 'push');
            app.reset_pbutton.ButtonPushedFcn = createCallbackFcn(app, @reset_pbutton_Callback, true);
            app.reset_pbutton.Tag = 'reset_pbutton';
            app.reset_pbutton.Text = 'Reset';
            app.createparfile_pbutton = uibutton(app.figure1, 'push');
            app.createparfile_pbutton.ButtonPushedFcn = createCallbackFcn(app, @createparfile_pbutton_Callback, true);
            app.createparfile_pbutton.Tag = 'createparfile_pbutton';
            app.createparfile_pbutton.Text = 'Create *.PAR file';
            app.fastpardat_pbutton = uibutton(app.figure1, 'push');
            app.fastpardat_pbutton.ButtonPushedFcn = createCallbackFcn(app, @fastpardat_pbutton_Callback, true);
            app.fastpardat_pbutton.Tag = 'fastpardat_pbutton';
            app.fastpardat_pbutton.Text = 'Fast _partdat';
            app.uipanel10 = uipanel(app.figure1);
            app.uipanel10.Title = 'BATCH';
            app.uipanel10.Tag = 'uipanel10';
            app.uipanel11 = uipanel(app.uipanel10);
            app.uipanel11.Title = 'Select Analyser';
            app.uipanel11.Tag = 'uipanel11';
            app.hrtbatch_pbutton = uibutton(app.uipanel11, 'push');
            app.hrtbatch_pbutton.Tag = 'hrtbatch_pbutton';
            app.hrtbatch_pbutton.Text = 'HR Turbulance';
            app.hrvbatch_pbutton = uibutton(app.uipanel11, 'push');
            app.hrvbatch_pbutton.ButtonPushedFcn = createCallbackFcn(app, @hrvbatch_pbutton_Callback, true);
            app.hrvbatch_pbutton.Tag = 'hrvbatch_pbutton';
            app.hrvbatch_pbutton.Text = 'HR Variability';
            app.isobatch_pbutton = uibutton(app.uipanel11, 'push');
            app.isobatch_pbutton.Tag = 'isobatch_pbutton';
            app.isobatch_pbutton.Text = 'Isometric';
            app.dbbatch_pbutton = uibutton(app.uipanel11, 'push');
            app.dbbatch_pbutton.Tag = 'dbbatch_pbutton';
            app.dbbatch_pbutton.Text = 'Deep Breathing';
            app.standbatch_pbutton = uibutton(app.uipanel11, 'push');
            app.standbatch_pbutton.Tag = 'standbatch_pbutton';
            app.standbatch_pbutton.Text = 'Standing';
            app.valsbatch_pbutton = uibutton(app.uipanel11, 'push');
            app.valsbatch_pbutton.Tag = 'valsbatch_pbutton';
            app.valsbatch_pbutton.Text = 'Valsalva';
            app.pushbutton45 = uibutton(app.uipanel10, 'push');
            app.pushbutton45.Tag = 'pushbutton45';
            app.pushbutton45.Text = 'Synchronize Files';
            app.Untitled_3 = uicontextmenu(app.figure1);
            app.Untitled_3.Tag = 'Untitled_3';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CORWAVE_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)CORWAVE_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end